classdef analisis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GenerarEspectrodepotenciasparaelsujetoseleccionadoButton  matlab.ui.control.Button
        AnalisisButton             matlab.ui.control.Button
        GenerarxlsxButton          matlab.ui.control.Button
        AbrirButton                matlab.ui.control.Button
        GuardarenButton            matlab.ui.control.Button
        guardarenEditField         matlab.ui.control.EditField
        carpetasListBox            matlab.ui.control.ListBox
        SelecBMPButton             matlab.ui.control.Button
        bpmfolderEditField         matlab.ui.control.EditField
        outpytsystemTextArea       matlab.ui.control.TextArea
        SeleccionarButton          matlab.ui.control.Button
        seleccionardatosEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
       BMPfiles_L
       dataDIR_L
       exportDIR_L
       EEG_timestamps_L
       debug
       canales = {'tiempo','Fp1-A1' , 'Fp2-A2','F3-A1','F4-A2','C3-A1','C4-A2','P3-A1','P4-A2','O1-A1','O2-A2','F7-A1','F8-A2','T3-A1',...
           'T4-A2','T5-A1','T6-A2','Add_lead1','Add_lead2','Add_lead3','Add_lead4','Add_lead5','Add_lead6','Add_lead7','Add_lead8','Add_lead9','Add_lead10','EKG'};
       canales2 = {'frecuencia','Fp1-A1' , 'Fp2-A2','F3-A1','F4-A2','C3-A1','C4-A2','P3-A1','P4-A2','O1-A1','O2-A2','F7-A1','F8-A2','T3-A1',...
           'T4-A2','T5-A1','T6-A2','Add_lead1','Add_lead2','Add_lead3','Add_lead4','Add_lead5','Add_lead6','Add_lead7','Add_lead8','Add_lead9','Add_lead10'};
       %canales_data=[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20,21,22,23,24,25,26,27];
       pupil_header = {'tiempo', 'diametro mm'};
       respuestas = {'Timeout','Nada','Bala'};
       MinPeakHeight_L
       MinPeakDistance_L
       lista
       sujeto_n = 1;
       fs = 200;
       ventana = 200;
       freq_RL
       t
       
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %
            load("memoria_analisis.mat","BMPfiles");load("memoria_analisis.mat","dataDIR");load("memoria_analisis.mat","exportDIR");
            load("memoria_analisis.mat","MinPeakHeight");load("memoria_analisis.mat","MinPeakDistance");
            %
            app.GenerarxlsxButton.Enable="off";
            app.AnalisisButton.Enable="off";
            %
            app.seleccionardatosEditField.Editable="off";
            app.bpmfolderEditField.Editable="off";
            app.guardarenEditField.Editable="off";
            %cargamos los datos de memoria
            app.BMPfiles_L = BMPfiles;
            app.dataDIR_L = dataDIR;
            app.exportDIR_L = exportDIR;
            app.MinPeakHeight_L = MinPeakHeight;
            app.MinPeakDistance_L = MinPeakDistance;
            %modificamos los valores de los campos de edición
            app.seleccionardatosEditField.Value = app.dataDIR_L;
            app.bpmfolderEditField.Value = app.BMPfiles_L;
            app.guardarenEditField.Value = app.exportDIR_L;
            %app.MinPeakHeightuVEditField.Value = app.MinPeakHeight_L;
            %app.MinPeakDistanceEditField.Value = app.MinPeakDistance_L;
            %
            app.debug = "Seleccione sus datos. [OK] Variables de memoria cargadas."+...
                newline+"Los datos exportados serán guardados en "+convertCharsToStrings(app.exportDIR_L);% + convertCharsToStrings(convert_timestamp(app.EEG_timestamps_L));
            app.outpytsystemTextArea.Value = app.debug;
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.seleccionardatosEditField.Value = app.dataDIR_L;
            else
                app.seleccionardatosEditField.Value = directorio;
                dataDIR = directorio;
                app.dataDIR_L = dataDIR;
                save('memoria_analisis.mat','dataDIR','-append');
                app.debug =  "[OK] Directorio de datos cargado " + convertCharsToStrings(directorio)+ newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Button pushed function: SelecBMPButton
        function SelecBMPButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.bpmfolderEditField.Value = app.BMPfiles_L;
            else
                app.bpmfolderEditField.Value = directorio;
                BMPfiles = directorio;
                app.BMPfiles_L = BMPfiles;
                save('memoria_analisis.mat','BMPfiles','-append');
                app.debug =  "[OK] Directorio BMP cargado " + convertCharsToStrings(directorio)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Button pushed function: GuardarenButton
        function GuardarenButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.guardarenEditField.Value = app.BMPfiles_L;
            else
                app.guardarenEditField.Value = directorio;
                exportDIR = directorio;
                app.exportDIR_L = exportDIR;
                save('memoria_analisis.mat','exportDIR','-append');
                app.debug =  "[OK] Los datos serán guardados en" + convertCharsToStrings(directorio)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.debug = "Verificando exitencia de archivos necesarios..."+newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            if isempty(listaArchivos(app.BMPfiles_L,'*.bmp')) == 1
               app.debug = "[X] Carpeta de imagenes no contiene archivos BMP. ABORTANDO"+newline+app.debug;
               app.outpytsystemTextArea.Value = app.debug;
               c_bmp = 0;
            else
                NUM = length(listaArchivos(app.BMPfiles_L,'*.bmp'));
                app.debug = "[OK] Num. Archivos BMP = " + int2str(NUM)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                c_bmp = 1;
            end
            %%%%%%%
            % listamos las carpetas en el directorio 
            lista_directorios = SubDirectorios1nivel(app.dataDIR_L);
            app.lista={};
            if isempty(lista_directorios) == 1
                app.debug = "[X] Carpeta vacía."+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
            else
                for i = 1:length(lista_directorios)
                    directorio =  fullfile(app.dataDIR_L,convertCharsToStrings(lista_directorios(i)));
                    c_pupil = validador_pupil(directorio);
                    c_eeg = validador_eeg24_tarea(directorio);
                    if c_pupil == 1 && c_eeg == 1
                            app.lista(end+1)=lista_directorios(i);
                            
                    end
                end
                if length(app.lista) >= 2 && c_bmp == 1
                   app.carpetasListBox.Items = app.lista;
                   sujeto_index = cell(1,length(app.lista));
                   for h = 1:length(app.lista)
                        sujeto_index(h) = {[h]}; 
                   end
                   app.carpetasListBox.ItemsData = sujeto_index;
                   app.GenerarxlsxButton.Enable ="on";
                   app.debug = "[OK] Carpetas compatibles encontradas. Total = "+int2str(length(app.lista))+newline+app.debug;
                   app.outpytsystemTextArea.Value = app.debug;
                else
                   app.debug = "[X] Carpetas incompatibles o imágenes BMP no encontradas."+newline+app.debug;
                   app.outpytsystemTextArea.Value = app.debug;
                end
            end
            
        end

        % Button pushed function: GenerarxlsxButton
        function GenerarxlsxButtonPushed(app, event)
            app.debug = "Generando hojas de datos XLSX."+newline+app.debug;
            app.outpytsystemTextArea.Value = app.debug;
            app.SeleccionarButton.Enable="off";
            app.SelecBMPButton.Enable = "off";
            app.GuardarenButton.Enable = "off";
            app.AbrirButton.Enable="off";
            app.GenerarxlsxButton.Enable="off";
            app.AnalisisButton.Enable = "off";
            %app.MinPeakDistanceEditField.Editable = "off";
            %app.MinPeakHeightuVEditField.Editable = "off";
            drawnow limitrate
            for i = 1:length(app.lista)
                %% generamos los nombres de los archivos
                ID=convertStringsToChars(strjoin(regexp(convertCharsToStrings(app.lista(i)),'[0-9]','match'),''));
                archivo_eegk = ['EEGK_' ID '.xlsx'];
                archivo_eegk_l = ['EEGK_L_' ID '.xlsx'];
                archivo_eegk_r = ['EEGK_R_' ID '.xlsx'];
                archivo_eegk_fft = ['EEGK_fft_' ID '.xlsx'];
                archivo_eegk_l_fft = ['EEGK_fft_L_' ID '.xlsx'];
                archivo_eegk_r_fft = ['EEGK_fft_R_' ID '.xlsx'];
                archivo_pupil_diam= ['pupil_diam_' ID '.xlsx'];
                archivo_pupil_diam_l = ['pupil_diam_L_' ID '.xlsx'];    
                archivo_pupil_diam_r = ['pupil_diam_R_' ID '.xlsx'];
                archivo_xy_pos = ['xy_pos' ID '.xlsx'];
                subdataDIR = fullfile(app.dataDIR_L,char(app.lista(i)));
                emoFlechas_file = EmoFlechas_finder(fullfile(subdataDIR,'eeg_tarea'));
                eegk_file = EEGdata_finder(fullfile(subdataDIR,'eeg_tarea'));
                app.EEG_timestamps_L = load(fullfile(subdataDIR,'eeg_tarea','eeg_timestamp.txt'));
                EmoFlechas_data = loadEmoFlechas(fullfile(subdataDIR,'eeg_tarea',emoFlechas_file));
                EEG_data = loadEEG(fullfile(subdataDIR,'eeg_tarea',eegk_file),app.EEG_timestamps_L);
                %% Pupil data
                delete(fullfile(app.exportDIR_L,archivo_pupil_diam));delete(fullfile(app.exportDIR_L,archivo_pupil_diam_l))
                delete(fullfile(app.exportDIR_L,archivo_pupil_diam_r));
                data_json = readjson(fullfile(subdataDIR,'info.player.json'));
                pupil_timestamp = data_json.start_time_system_s - data_json.start_time_synced_s;
                pupil_diam_dir = fullfile(subdataDIR,'export_data','pupil_positions.csv');
                pupil_data_diameter = readtable(pupil_diam_dir);
                pupil_data_diameter_time=pupil_data_diameter.pupil_timestamp;
                app.debug = "[OK] info.player.json cargado. Inicio de experimento eye tracker: "+convertCharsToStrings(convert_timestamp(data_json.start_time_system_s))...;
                   +newline+"Duracion del experimento: " + sprintf('%.2f',data_json.duration_s/60)+" minutos"+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                %% [EEG-EKG] 
                delete(fullfile(app.exportDIR_L,archivo_eegk));delete(fullfile(app.exportDIR_L,archivo_eegk_l))
                delete(fullfile(app.exportDIR_L,archivo_eegk_r));
                app.debug = "Generando "+convertCharsToStrings(archivo_eegk)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                app.debug = "Comparando el archivo"+convertCharsToStrings(emoFlechas_file)+" con " +convertCharsToStrings(eegk_file)...
                +newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;        
                indices = sync_EEG_EF(EEG_data,EmoFlechas_data);
                %% fourier
                delete(fullfile(app.exportDIR_L,archivo_eegk_fft));delete(fullfile(app.exportDIR_L,archivo_eegk_l_fft));
                delete(fullfile(app.exportDIR_L,archivo_eegk_r_fft));
                
                for j = 1:length(EmoFlechas_data.Var4)
                    signals = zeros(indices(j,2)-indices(j,1)+1,length(app.canales));
                    signals_left  = zeros(app.ventana,length(app.canales));
                    signals_right = zeros(app.ventana ,length(app.canales));
                    pupil_diameter = zeros(indices(j,2)-indices(j,1)+1,2);
                    pupil_diameter_l = zeros(app.ventana,2);
                    pupil_diameter_r = zeros(app.ventana,2);
                    %length(signals)
                    %N = length(signals);
                    N_RL = length(signals_right);
                    app.freq_RL = 0:app.fs/N_RL:app.fs/2;
                    app.t = 0:1/app.fs:1-1/app.fs;
                    %signals_fft = zeros(indices(j,2)-indices(j,1)+1,length(app.canales));
                    signals_left_fft  = zeros(length(app.freq_RL),length(app.canales2));
                    signals_right_fft = zeros(length(app.freq_RL),length(app.canales2));
                    signals_right_fft(:,1) = app.freq_RL;
                    signals_left_fft(:,1) = app.freq_RL;
                    for n = 1:length(app.canales)-1
                        x = EEG_data(indices(j,1):indices(j,2),n);
                        x_l = EEG_data(indices(j,1)-app.ventana:indices(j,1)-1,n);
                        x_r = EEG_data(indices(j,2)+1:indices(j,2)+app.ventana,n);
                        %%
                        signals(:,n) = x;
                        %xdft = fft(x);
                        %xdft = xdft(1:N/2+1);
                        %psdx = (1/(fs*N)) * abs(xdft).^2;
                        %psdx(2:end-1) = 2*psdx(2:end-1);
                        %%
                        signals_left(:,n) = x_l;
                        if n >= 2 
                            xdft = fft(x_l);
                            xdft = xdft(1:N_RL/2+1);
                            psdx = (1/(app.fs*N_RL)) * abs(xdft).^2;
                            psdx(2:end-1) = 2*psdx(2:end-1);
                            signals_left_fft(:,n) = pow2db(psdx);
                        end

                        %%
                        signals_right(:,n) = x_r;
                        if n >= 2 
                            xdft = fft(x_r);
                            xdft = xdft(1:N_RL/2+1);
                            psdx = (1/(app.fs*N_RL)) * abs(xdft).^2;
                            psdx(2:end-1) = 2*psdx(2:end-1);
                            signals_right_fft(:,n) = pow2db(psdx);
                        end

                    end
                    %% PUPIL DATA
                    % center
                    pupil_diameter(:,1)   = signals(:,1);
                    pupil_diameter_index = sync_EEG_pupil_2(signals(:,1), pupil_data_diameter_time,pupil_timestamp);
                    pupil_diameter(:,2) = pupil_data_diameter.diameter(pupil_diameter_index);
                    data_cells=num2cell(pupil_diameter);
                    output_matrix=[app.pupil_header;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_pupil_diam),'Sheet',int2str(j)); 
                    % left
                    pupil_diameter_l(:,1) = signals_left(:,1);
                    pupil_diameter_index = sync_EEG_pupil_2(signals_left(:,1), pupil_data_diameter_time,pupil_timestamp);
                    pupil_diameter_l(:,2) = pupil_data_diameter.diameter(pupil_diameter_index);
                    data_cells=num2cell(pupil_diameter_l);
                    output_matrix=[app.pupil_header;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_pupil_diam_l),'Sheet',int2str(j)); 
                    % right
                    pupil_diameter_r(:,1) = signals_right(:,1);
                    pupil_diameter_index = sync_EEG_pupil_2(signals_right(:,1), pupil_data_diameter_time    ,pupil_timestamp);
                    pupil_diameter_r(:,2) = pupil_data_diameter.diameter(pupil_diameter_index);
                    data_cells=num2cell(pupil_diameter_r);
                    output_matrix=[app.pupil_header;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_pupil_diam_r),'Sheet',int2str(j)); 

                    clear data_cells;
                    %% EGG DATA
                    signals(:,end) = EEG_data(indices(j,1):indices(j,2),20)-EEG_data(indices(j,1):indices(j,2),19);
                    signals_left(:,end) = EEG_data(indices(j,1)-app.ventana:indices(j,1)-1,20)-EEG_data(indices(j,1)-app.ventana:indices(j,1)-1,19);
                    signals_right(:,end) = EEG_data(indices(j,2)+1:indices(j,2)+app.ventana,20)-EEG_data(indices(j,2)+1:indices(j,2)+app.ventana,19);
                    data_cells=num2cell(signals);
                    output_matrix=[app.canales;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk),'Sheet',int2str(j)); 
                    data_cells_l=num2cell(signals_left);
                    output_matrix=[app.canales;  data_cells_l];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk_l),'Sheet',int2str(j)); 
                    data_cells_r=num2cell(signals_right);
                    output_matrix=[app.canales;  data_cells_r];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk_r),'Sheet',int2str(j));
                    clear data_cells;
                    %% fourier
                    %left
                    data_cells=num2cell(signals_left_fft);
                    output_matrix=[app.canales2;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk_l_fft),'Sheet',int2str(j)); 
                    %rigth
                    data_cells=num2cell(signals_right_fft);
                    output_matrix=[app.canales2;  data_cells];
                    writecell(output_matrix,fullfile(app.exportDIR_L,archivo_eegk_r_fft),'Sheet',int2str(j)); 
                end
                app.debug = "[OK] Archivos escritos: "+convertCharsToStrings(archivo_eegk)+", "+convertCharsToStrings(archivo_eegk_l)+", "+convertCharsToStrings(archivo_eegk_r)+newline+app.debug;
                app.outpytsystemTextArea.Value = app.debug;
                %% [

                
            end
            app.GenerarxlsxButton.Enable="on";
        end

        % Value changed function: carpetasListBox
        function carpetasListBoxValueChanged(app, event)
            app.sujeto_n = app.carpetasListBox.Value;
            
        end

        % Button pushed function: 
        % GenerarEspectrodepotenciasparaelsujetoseleccionadoButton
        function GenerarEspectrodepotenciasparaelsujetoseleccionadoButtonPushed(app, event)
            sujeto_dir = app.lista(app.sujeto_n);
            subdataDIR = fullfile(app.dataDIR_L,char(sujeto_dir));
            emoFlechas_file = EmoFlechas_finder(fullfile(subdataDIR,'eeg_tarea'));
            EmoFlechas_data =  loadEmoFlechas(fullfile(subdataDIR,'eeg_tarea',emoFlechas_file));
            ID=convertStringsToChars(strjoin(regexp(convertCharsToStrings(sujeto_dir),'[0-9]','match'),''));
            %archivo_eegk = ['EEGK_' ID '.xlsx'];
            archivo_eegk_l = ['EEGK_L_' ID '.xlsx'];
            archivo_eegk_r = ['EEGK_R_' ID '.xlsx'];
            %archivo_eegk_fft = ['EEGK_fft_' ID '.xlsx'];
            archivo_eegk_l_fft = ['EEGK_fft_L_' ID '.xlsx'];
            archivo_eegk_r_fft = ['EEGK_fft_R_' ID '.xlsx'];
            %respuesta_index= 1; %
            for respuesta_index = 1:length(app.respuestas)
                N = 0;
                
                signals_left  = zeros(app.ventana,length(app.canales));
                signals_right = zeros(app.ventana ,length(app.canales));
                N_RL = length(signals_right);
                app.freq_RL = 0:app.fs/N_RL:app.fs/2;
                app.t = 0:1/app.fs:1-1/app.fs;
                signals_left_fft  = zeros(length(app.freq_RL),length(app.canales2));
                signals_right_fft = zeros(length(app.freq_RL),length(app.canales2));
                %
                
                respuesta_01=strcmp(EmoFlechas_data.Var2,app.respuestas(respuesta_index));
                for i =1:length(EmoFlechas_data.Var2)
                    if respuesta_01(i) == 1
                        datos_eeg_L =  readmatrix(fullfile(app.exportDIR_L,archivo_eegk_l),'Sheet',int2str(i));
                        datos_eeg_R =  readmatrix(fullfile(app.exportDIR_L,archivo_eegk_r),'Sheet',int2str(i));
                        datos_fft_L = readmatrix(fullfile(app.exportDIR_L,archivo_eegk_l_fft),'Sheet',int2str(i));
                        datos_fft_R = readmatrix(fullfile(app.exportDIR_L,archivo_eegk_r_fft),'Sheet',int2str(i));
                        signals_left = signals_left+datos_eeg_L;
                        signals_right = signals_right+datos_eeg_R; 
                        signals_left_fft = signals_left_fft + datos_fft_L;
                        signals_right_fft = signals_right_fft + datos_fft_R;
                        N=N+1;
                    end
                end
                signals_left = signals_left/N;
                signals_right = signals_right/N;
                signals_right_fft = signals_right_fft/N;
                signals_left_fft = signals_left_fft/N;
                for k = 2:length(app.canales)-1
                    gen_figs_pdf(app.exportDIR_L,ID,char(app.canales(k)),app.t,app.freq_RL, ...
                        signals_left(:,k),signals_right(:,k),signals_left_fft(:,k),signals_right_fft(:,k), ...
                        char(app.respuestas(respuesta_index)));
                end
            end
            app.delete;
            
            %exportDIR_L
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.seleccionardatosEditField = uieditfield(app.UIFigure, 'text');
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.outpytsystemTextArea = uitextarea(app.UIFigure);
            app.outpytsystemTextArea.Value = {'output'};
            app.bpmfolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelecBMPButton = uibutton(app.UIFigure, 'push');
            app.SelecBMPButton.ButtonPushedFcn = createCallbackFcn(app, @SelecBMPButtonPushed, true);
            app.SelecBMPButton.Text = 'Selec. BMP';
            app.carpetasListBox = uilistbox(app.UIFigure);
            app.carpetasListBox.Items = {'Sujeto1', 'Sujeto2', 'Sujeto3', 'Sujeto4'};
            app.carpetasListBox.ValueChangedFcn = createCallbackFcn(app, @carpetasListBoxValueChanged, true);
            app.carpetasListBox.Value = 'Sujeto1';
            app.guardarenEditField = uieditfield(app.UIFigure, 'text');
            app.GuardarenButton = uibutton(app.UIFigure, 'push');
            app.GuardarenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarenButtonPushed, true);
            app.GuardarenButton.Text = 'Guardar en';
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.GenerarxlsxButton = uibutton(app.UIFigure, 'push');
            app.GenerarxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarxlsxButtonPushed, true);
            app.GenerarxlsxButton.Text = 'Generar .xlsx';
            app.AnalisisButton = uibutton(app.UIFigure, 'push');
            app.AnalisisButton.Text = 'Analisis';
            app.GenerarEspectrodepotenciasparaelsujetoseleccionadoButton = uibutton(app.UIFigure, 'push');
            app.GenerarEspectrodepotenciasparaelsujetoseleccionadoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarEspectrodepotenciasparaelsujetoseleccionadoButtonPushed, true);
            app.GenerarEspectrodepotenciasparaelsujetoseleccionadoButton.Text = 'Generar Espectro de potencias para el sujeto seleccionado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analisis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end