classdef main2_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SeleccioneunaopcinLabel  matlab.ui.control.Label
        VerdatosButton_2         matlab.ui.control.Button
        GenerarAnalisisButton    matlab.ui.control.Button
        SalirButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            verdatosfig = fullfile("figures","data-read.gif");
            analisisdatosfig = fullfile("figures","data-analysis.gif");
            app.GenerarAnalisisButton.Icon = analisisdatosfig;
            app.VerdatosButton_2.Icon = verdatosfig;
            app.VerdatosButton_2.IconAlignment = 'top';
            app.GenerarAnalisisButton.IconAlignment = 'top';
        end

        % Button pushed function: VerdatosButton_2
        function VerdatosButton_2Pushed(app, event)
            resultados_app
            app.delete;
        end

        % Button pushed function: GenerarAnalisisButton
        function GenerarAnalisisButtonPushed(app, event)
            analisis_app    
            app.delete;
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SalirButton = uibutton(app.UIFigure, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.GenerarAnalisisButton = uibutton(app.UIFigure, 'push');
            app.GenerarAnalisisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarAnalisisButtonPushed, true);
            app.GenerarAnalisisButton.Text = 'Generar Analisis';
            app.VerdatosButton_2 = uibutton(app.UIFigure, 'push');
            app.VerdatosButton_2.ButtonPushedFcn = createCallbackFcn(app, @VerdatosButton_2Pushed, true);
            app.VerdatosButton_2.Text = 'Ver datos';
            app.SeleccioneunaopcinLabel = uilabel(app.UIFigure);
            app.SeleccioneunaopcinLabel.Text = 'Seleccione una opción';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main2_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end