classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        PupilEEGKLoaderLabel  matlab.ui.control.Label
        IniciandoButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    figunam = fullfile('figures','unam_logo.png');
    figc3 = fullfile('figures','c3_logo.png');
    app.Image.ImageSource = figunam; app.Image2.ImageSource = figc3;
    app.IniciandoButton.Text = 'Cargando...';
    app.IniciandoButton.IconAlignment = 'bottom';
    wbar = permute(repmat(app.IniciandoButton.BackgroundColor,15,1,200),[1,3,2]);
    wbar([1,end],:,:) = 0;
    wbar(:,[1,end],:) = 0;
    app.IniciandoButton.Icon = wbar;
    n = 100;
     for i = 1:n
         if mod(i,10)==0
         currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
         RGB = app.IniciandoButton.Icon;
         RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; 
         RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
         RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;

           app.IniciandoButton.Icon = RGB;
          pause(.5)
          end
     end
     pause(1)
     delete(app)
     main2_app
        end

        % Button pushed function: IniciandoButton
        function IniciandoButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IniciandoButton = uibutton(app.UIFigure, 'push');
            app.IniciandoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciandoButtonPushed, true);
            app.IniciandoButton.Text = 'Iniciando....';
            app.PupilEEGKLoaderLabel = uilabel(app.UIFigure);
            app.PupilEEGKLoaderLabel.Text = 'PupilEEGKLoader';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end