classdef resultados_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image3                         matlab.ui.control.Image
        tiempoeditfieldEditField       matlab.ui.control.EditField
        TiempodeRespuestaLabel         matlab.ui.control.Label
        AccinEditField                 matlab.ui.control.EditField
        AccinEditFieldLabel            matlab.ui.control.Label
        DuracinmsEditField             matlab.ui.control.NumericEditField
        DuracinmsEditFieldLabel        matlab.ui.control.Label
        ParteEditField                 matlab.ui.control.NumericEditField
        ParteEditFieldLabel            matlab.ui.control.Label
        CanalDropDown                  matlab.ui.control.DropDown
        CanalDropDownLabel             matlab.ui.control.Label
        CerrarButton                   matlab.ui.control.Button
        PlayPausaButton                matlab.ui.control.Button
        TiempoSlider                   matlab.ui.control.Slider
        TiempoSliderLabel              matlab.ui.control.Label
        MinPeakHeightuVEditField       matlab.ui.control.NumericEditField
        MinPeakHeightuVEditFieldLabel  matlab.ui.control.Label
        MinPeakDistanceEditField       matlab.ui.control.NumericEditField
        MinPeakDistanceEditFieldLabel  matlab.ui.control.Label
        bpmEditField                   matlab.ui.control.NumericEditField
        bpmEditFieldLabel              matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        DatosCicloDropDown             matlab.ui.control.DropDown
        DatosCicloDropDownLabel        matlab.ui.control.Label
        CargarHeatmapspreviosCheckBox  matlab.ui.control.CheckBox
        SiguienteButton                matlab.ui.control.Button
        AnteriorButton                 matlab.ui.control.Button
        CargardatosButton              matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        outputsysTextArea              matlab.ui.control.TextArea
        exportListBox                  matlab.ui.control.ListBox
        AbrircarpetaButton             matlab.ui.control.Button
        SelecBMPButton                 matlab.ui.control.Button
        SeleccionarButton              matlab.ui.control.Button
        directoriobmppngEditField      matlab.ui.control.EditField
        directoriodataEditField        matlab.ui.control.EditField
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       BMPfiles_L
       dataDIR_L
       EEG_timestamps_L
       debug
       data_json
       canales = {'Fp1-A1' , 'Fp2-A2','F3-A1','F4-A2','C3-A1','C4-A2','P3-A1','P4-A2','O1-A1','O2-A2','F7-A1','F8-A2','T3-A1',...
           'T4-A2','T5-A1','T6-A2','Add_lead1','Add_lead2','Add_lead3','Add_lead4','Add_lead5','Add_lead6','Add_lead7','Add_lead8','Add_lead9','Add_lead10'};
       canales_data=[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20,21,22,23,24,25,26];
       dpc = {'x10','x1','x2','x5','x20','x50','x100','x200'};
       %dpc_data = [1,2,5,10,20,50,100,200];
       dpc_element = 10;
       EEGfile
       EEGtimestampFile
       Emo_Flechasfile
       direc_exports
       direc_surfaces
       indices
       pupil_timestamp
       pupil_positions_dir;blinks_dir;eye0_mp4_dir;eye0_dir;gaze_positions_on_surface_dir
       pupil_positions_data;blinks_data;eye0_mp4_data;eye0_data;gaze_positions_on_surface_data
       pupil_positions_index;blinks_index;eye0_index;gaze_positions_on_surface_index
       global_time_sync 
       playing
       vector_tiempo
       h1;h2;h3;
       valor =1
       ECGdata
       EEG_grafica
       canal
       canal_index = 1;
       heatname
       MinPeakHeight_L
       MinPeakDistance_L
       frec_card_index
       bpm
       eeg_chanel
       eegplot
       EEG_data
       abs_ind
       dur_num
       accion
       loadHM = true;
       ones_area
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = fullfile('figures','waiting_forBMP.gif');
            app.Image3.ImageSource = fullfile('figures','waiting_forBMP.gif');
            app.Image2.ImageSource = fullfile('figures','frecard.gif');
            %%%%%%%
            load("memoria_resultados.mat","BMPfiles");load("memoria_resultados.mat","dataDIR");
            load("memoria_resultados.mat","MinPeakHeight");load("memoria_resultados.mat","MinPeakDistance");
            %load("info.mat","EEG_timestamps");
            app.BMPfiles_L = BMPfiles;
            app.dataDIR_L = dataDIR;
            %app.EEG_timestamps_L = EEG_timestamps;
            app.directoriodataEditField.Editable = false;
            app.directoriobmppngEditField.Editable = false;
            app.directoriodataEditField.Value = app.dataDIR_L;
            app.directoriobmppngEditField.Value = app.BMPfiles_L;
            %app.EEGtimestapmEditField.Value = app.EEG_timestamps_L;
            %
            app.MinPeakHeight_L = MinPeakHeight;
            app.MinPeakDistance_L = MinPeakDistance;
            app.MinPeakHeightuVEditField.Value = app.MinPeakHeight_L;
            app.MinPeakDistanceEditField.Value = app.MinPeakDistance_L;
            %
            app.CanalDropDown.Items = app.canales;
            app.CanalDropDown.ItemsData = app.canales_data;
            app.DatosCicloDropDown.Items=app.dpc;
           %app.DatosCicloDropDown.ItemsData=app.dpc_data;
            %textArea
            app.debug = "Seleccione sus datos. [OK] Variables de memoria cargadas.";% + convertCharsToStrings(convert_timestamp(app.EEG_timestamps_L));
            app.outputsysTextArea.Value = app.debug;
            %enables/disables
            app.exportListBox.Enable = false;
            app.CargardatosButton.Enable = false;
            app.AnteriorButton.Enable = false;
            app.SiguienteButton.Enable = false;
            app.PlayPausaButton.Enable = false;
            app.TiempoSlider.Enable = false;
            app.CargarHeatmapspreviosCheckBox.Value = app.loadHM;
            app.CanalDropDown.Enable = false;
            app.ParteEditField.Editable = false;
            app.DuracinmsEditField.Editable = false;
            app.AccinEditField.Editable = false;
            %app.outputsysTextArea.Editable = false;
            app.tiempoeditfieldEditField.Editable = false;
            app.bpmEditField.Editable = false;
            % otros
            %app.UIAxes4.XLim = [0,913];
            %app.UIAxes4.YLim = [0,721];
            
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.directoriodataEditField.Value = app.dataDIR_L;
            else
                app.directoriodataEditField.Value = directorio;
                dataDIR = directorio;
                app.dataDIR_L = dataDIR;
                save('memoria_resultados.mat','dataDIR','-append');
                app.debug =  "[OK] Directorio de datos cargado " + convertCharsToStrings(directorio)+ newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
            end
        end

        % Button pushed function: SelecBMPButton
        function SelecBMPButtonPushed(app, event)
            directorio = uigetdir;
            if directorio == 0
                app.directoriobmppngEditField.Value = app.BMPfiles_L;
            else
                app.directoriobmppngEditField.Value = directorio;
                BMPfiles = directorio;
                app.BMPfiles_L = BMPfiles;
                save('memoria_resultados.mat','BMPfiles','-append');
                app.debug =  "[OK] Directorio BMP cargado " + convertCharsToStrings(directorio)+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
            end
        end

        % Value changed function: MinPeakHeightuVEditField
        function MinPeakHeightuVEditFieldValueChanged(app, event)
            value = app.MinPeakHeightuVEditField.Value;
            MinPeakHeight = value;
            app.MinPeakHeight_L = MinPeakHeight;
            app.debug = "Variable MinPeakHeight actualizada."+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            save('memoria_resultados.mat',"MinPeakHeight","-append")
        end

        % Value changed function: MinPeakDistanceEditField
        function MinPeakDistanceEditFieldValueChanged(app, event)
            value = app.MinPeakDistanceEditField.Value;
            MinPeakDistance = value;
            app.MinPeakDistance_L = MinPeakDistance;
            app.debug = "Variable MinPeakDistance actualizada. " +newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            save('memoria_resultados.mat',"MinPeakDistance","-append")
        end

        % Value changed function: CargarHeatmapspreviosCheckBox
        function CargarHeatmapspreviosCheckBoxValueChanged(app, event)
            value = app.CargarHeatmapspreviosCheckBox.Value;
            app.loadHM=value;
        end

        % Button pushed function: AbrircarpetaButton
        function AbrircarpetaButtonPushed(app, event)
            app.debug = "Buscando archivos necesarios..."+newline+app.debug;app.outputsysTextArea.Value=app.debug;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if isafolder(app.dataDIR_L,'exports') == 1 && is_an_empty(fullfile(app.dataDIR_L,'exports'))  == 0
                c_exports = 1;
                app.exportListBox.Items = listaFolders(fullfile(app.dataDIR_L,'exports'));
                app.debug = "[OK] Subcarpetas /exports detectadas."+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
            else
                app.debug = "[X] Directotio /exports vacio o no existe."+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_exports = 0;
                
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
            if isempty(listaArchivos(app.BMPfiles_L,'*.bmp')) == 1
               app.debug = "[X] Carpeta de imagenes no contiene archivos BMP"+newline+app.debug;
               app.outputsysTextArea.Value = app.debug;
               c_bmp = 0;
            else
                NUM = length(listaArchivos(app.BMPfiles_L,'*.bmp'));
                app.debug = "[OK] Num. Archivos BMP = " + int2str(NUM)+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_bmp = 1;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if  isarchivo(app.dataDIR_L,'info.player.json') == 1
               app.data_json = readjson(fullfile(app.dataDIR_L,'info.player.json'));
               app.debug = "[OK] info.player.json encontrado. Inicio de experimento eye tracker: "+convertCharsToStrings(convert_timestamp(app.data_json.start_time_system_s))...;
                   +newline+"Duracion del experimento: " + sprintf('%.2f',app.data_json.duration_s/60)+" minutos"+newline+app.debug;
               
               app.outputsysTextArea.Value = app.debug;
               app.pupil_timestamp = app.data_json.start_time_system_s-app.data_json.start_time_synced_s;
                c_json = 1;
            else
                app.debug = "[X] info.player.json no encontrado"+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_json = 0;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.EEGfile = EEGdata_finder(fullfile(app.dataDIR_L,'eeg_tarea'));
            app.Emo_Flechasfile = EmoFlechas_finder(fullfile(app.dataDIR_L,'eeg_tarea'));
            set = length(app.EEGfile)*length(app.Emo_Flechasfile);
            if set == 0
                app.debug = "[X] Archivos XXXXXXXXX_data.txt o Prueba_Oficial.data.20XX-XX-XX--XX-XX.txt no encontrados"+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_eeg = 0;
            else
                app.debug = "[OK] Archivos " + convertCharsToStrings(app.Emo_Flechasfile) +" y "+convertCharsToStrings(app.EEGfile)+" encontrados."+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_eeg = 1;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %EEGtimestampFile
            if isarchivo(fullfile(app.dataDIR_L,'eeg_tarea'),'eeg_timestamp.txt') == 1
                app.EEGtimestampFile = fullfile(app.dataDIR_L,'eeg_tarea','eeg_timestamp.txt');
                app.debug = "[OK] archivo eeg_timestamp.txt encontrado"+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_eeg_timestamp = 1;
            else
                app.debug = "[X] Archivo eeg_timestamp.txt no encontrado."+newline+app.debug;
                app.outputsysTextArea.Value = app.debug;
                c_eeg_timestamp = 0;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if c_bmp == 1 && c_exports == 1 && c_json ==1 && c_eeg && c_eeg_timestamp
                app.exportListBox.Enable = true;
            else
                app.exportListBox.Enable = false;
            end
        end

        % Value changed function: exportListBox
        function exportListBoxValueChanged(app, event)
            value = app.exportListBox.Value;
            % buscamos los archivos restantes que usaremos: 
            % (1) ./exports/blinks.csv 
            % (2) ./exports/pupil_positions.csv
            % (3) ./exports/eye0_timestamps.csv
            % (4) ./exports/surfaces/gaze_positions_on_surface_XXXXXX.csv
            % (5) ./exports/eye0.mp4
            %;;;;
            
            app.direc_exports = fullfile(app.dataDIR_L,'exports',convertStringsToChars(value));
            app.direc_surfaces = fullfile(app.direc_exports,'surfaces');
            file_1 = isarchivo(app.direc_exports,'blinks.csv');
            if file_1 == true
                file_1 =1;
                   app.blinks_dir = fullfile(app.direc_exports,'blinks.csv');
            else
                file_1 =0;
            end
            file_2 = isarchivo(app.direc_exports,'pupil_positions.csv');
            if file_2 == true
                file_2 =1;
                   app.pupil_positions_dir = fullfile(app.direc_exports,'pupil_positions.csv');
            else
                file_2 =0;
            end
            %%
            file_3 = isarchivo(app.direc_exports,'eye0_timestamps.csv');
            if file_3 == true
                file_3 =1;
                app.eye0_dir = fullfile(app.direc_exports,'eye0_timestamps.csv');
            else
                file_3 =0;
            end
            %%
            file_4 = file_finder(app.direc_surfaces,'gaze_positions_on_surface');
            app.gaze_positions_on_surface_dir = fullfile(app.direc_surfaces,file_4);
            file_5 = isarchivo(app.direc_exports,'eye0.mp4');
            if file_5 == true
                file_5 =1;
                app.eye0_mp4_dir = fullfile(app.direc_exports,'eye0.mp4');
            else
                file_5 =0;
            end
            set = file_1*length(file_2)*length(file_3)*length(file_4)*file_5;
            if set == 0
                app.debug = "[X] Archivos faltantes"+newline+app.debug;  
                app.outputsysTextArea.Value = app.debug;
                app.CargardatosButton.Enable = false;
            else
                app.debug = "[OK] Archivos encontrados"+newline+app.debug;  
                app.outputsysTextArea.Value = app.debug;
                app.CargardatosButton.Enable = true;
            end
        end

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
             %   Archivos a cargar
            %   (1)'eye0.mp4'              &
            %   (2)'eye0_timestamps.csv'   &
            %   (3)'pupil_positions.csv'   &
            %   (4)'blinks.csv'            &
            %   (5)'gaze_positions_on_surface_Surface 1.csv'
            %app.EEG_timestamps_L 
            %%
            app.CerrarButton.Enable = false;
            app.MinPeakDistanceEditField.Editable = false;
            app.MinPeakHeightuVEditField.Editable = false;
            app.CargarHeatmapspreviosCheckBox.Enable = false;
            %% limpiamos las graficas en caso de volver a cargar datos

            %%
            app.CargardatosButton.Enable = false;
            app.debug = "Sincronizando datos y cargando variables..."+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% cargando eeg_timestamp.txt
            app.EEG_timestamps_L = load(app.EEGtimestampFile);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% EmoFlechas vs EEG
            EmoFlechas_data = loadEmoFlechas(fullfile(app.dataDIR_L,'eeg_tarea',app.Emo_Flechasfile));
            app.EEG_data = loadEEG(fullfile(app.dataDIR_L,'eeg_tarea',app.EEGfile),app.EEG_timestamps_L);            
            app.indices = sync_EEG_EF(app.EEG_data,EmoFlechas_data);
            app.abs_ind = app.indices(:,1)-app.indices(1,1)+1;
            tiempo = app.EEG_data(:,1);
            tiempo = tiempo(app.indices(1,1):app.indices(end,2));
            app.vector_tiempo = tiempo-tiempo(1);
            app.EEG_grafica = app.EEG_data;
            app.debug = "[OK] Sincronizandos: " + convertCharsToStrings(app.EEGfile) + " vs " +...
                convertCharsToStrings(app.Emo_Flechasfile)+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
           pause(0.1) 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% EEG vs pupil_positions
            %vector de tiempo de EEG
            app.global_time_sync = app.EEG_data(app.indices(1,1):app.indices(end,2),1);
            %
            app.pupil_positions_index = sync_EEG_pupil(app.global_time_sync,app.pupil_positions_dir,app.pupil_timestamp);
            app.pupil_positions_data = readtable(app.pupil_positions_dir);
            app.debug = "[OK] Sincronizandos: " + convertCharsToStrings(app.EEGfile) + " vs " +...
                "pupil_positions.csv"+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% EEG vs eye0
            app.eye0_index = sync_EEG_pupil(app.global_time_sync,app.eye0_dir,app.pupil_timestamp);
            app.eye0_data = VideoReader(app.eye0_mp4_dir);
            app.debug = "[OK] Sincronizandos: " + convertCharsToStrings(app.EEGfile) + " vs " +...
                "eye0.mp4"+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
           pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% EEG vs gazeOnsurface
            app.gaze_positions_on_surface_index = sync_EEG_pupil(app.global_time_sync,app.gaze_positions_on_surface_dir,app.pupil_timestamp);
            app.gaze_positions_on_surface_data = readtable(app.gaze_positions_on_surface_dir);
            app.debug = "[OK] Sincronizandos: " + convertCharsToStrings(app.EEGfile) + " vs " +...
                "gaze_positions_on_surface"+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ECG
            app.ECGdata =  app.EEG_data(app.indices(1,1):app.indices(end,2),20)-app.EEG_data(app.indices(1,1):app.indices(end,2),19);
            [app.bpm, locs_Rwave] = loadECG(app.ECGdata,app.indices,app.EEG_data(:,1),app.MinPeakHeight_L, app.MinPeakDistance_L);
            app.frec_card_index = sync_EEG_FrecCard(tiempo,app.bpm(:,2));
            app.debug = "[OK] ECG cargado "+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Mapas de calor
            app.debug = "Cargando mapas de calor... "+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
           
            if app.loadHM == false
            %csvwrite('test.txt',app.gaze_positions_on_surface_index.')            
            %print()
            %   print(app.gaze_positions_on_surface_data)
            generateAll_heat_maps(app.BMPfiles_L,EmoFlechas_data,app.indices,app.gaze_positions_on_surface_index, ...
            app.gaze_positions_on_surface_data);
            app.debug = "[OK] Mapas de calor generados en el directorio" + ...
                convertCharsToStrings(fullfile(app.BMPfiles_L,'heatmaps'))+newline+app.debug;
            elseif app.loadHM == true
                app.debug = "[OK] Mapas de calor previos seleccionados en el directorio" + ...
                convertCharsToStrings(fullfile(app.BMPfiles_L,'heatmaps'))+newline+app.debug;
            end
            app.heatname = loadHeatMap(EmoFlechas_data,app.indices);
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Otros datos
            app.debug = "Cargando datos relacionados con "+convertCharsToStrings(app.Emo_Flechasfile)+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            [app.dur_num, app.accion] = loadTaskAction(app.indices,EmoFlechas_data);
            app.debug = "[OK] indices relacionados con: "+convertCharsToStrings(app.Emo_Flechasfile)+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            pause(0.1)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Parpadeo
            app.ones_area = sync_EEG_blinks(app.blinks_dir,app.pupil_timestamp,app.global_time_sync);
            app.debug = "[OK] Sincronizandos: " + convertCharsToStrings(app.EEGfile) + " vs " +...
                "blinks.csv"+newline+app.debug;
            app.outputsysTextArea.Value = app.debug;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.TiempoSlider.Limits = [1,app.indices(end,2)-app.indices(1,1)+1];
            app.TiempoSlider.Enable = true;
            app.tiempoeditfieldEditField.Value = "1";
            %%% duracion - accion
            app.ParteEditField.Value = app.dur_num(1,1);
            app.DuracinmsEditField.Value = app.dur_num(1,2);
            app.AccinEditField.Value = char(app.accion(1));

            %%% ajuste de eje X
            app.UIAxes2.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            %% YLims
            app.UIAxes2.YLim = [min(app.ECGdata),max(app.ECGdata)];
            app.UIAxes3.YLim = [min(min(app.EEG_data(:,2:end)))/5,max(max(app.EEG_data(:,2:end)))/5];
            UIaxesXmax = max(app.pupil_positions_data.diameter)/2; 
            app.ones_area = app.ones_area*UIaxesXmax;
            app.UIAxes.YLim = [min(app.pupil_positions_data.diameter),UIaxesXmax];

            %% previews
            app.Image.ImageSource = fullfile(app.BMPfiles_L,'heatmaps',char(app.heatname(1)));

            app.bpmEditField.Value = app.bpm(app.frec_card_index(1),1);
            %frames_eye0 = flipud(read(app.eye0_data,app.eye0_index(1)));
            %imshow(frames_eye0, 'Parent', app.UIAxes4);
            app.Image3.ImageSource = flipud(read(app.eye0_data,app.eye0_index(1)));
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,app.vector_tiempo,app.ECGdata,'-r','LineWidth',1)
            plot(app.UIAxes2,app.vector_tiempo(locs_Rwave),app.ECGdata(locs_Rwave),'rv','MarkerFaceColor','green');
            hold(app.UIAxes2,'off')

            hold(app.UIAxes3,'on')
            app.eeg_chanel = app.EEG_grafica(app.indices(1,1):app.indices(end,2),app.canal_index+1);
            area(app.UIAxes3,app.vector_tiempo,app.ones_area*1000,'LineWidth',2,'FaceAlpha',0.5)
            area(app.UIAxes3,app.vector_tiempo,-app.ones_area*1000,'LineWidth',2,'FaceAlpha',0.5)
            app.eegplot = plot(app.UIAxes3,app.vector_tiempo,app.eeg_chanel,'-b','LineWidth',1);
            app.h2 = xline(app.UIAxes3,app.vector_tiempo(1),'-r','LineWidth',1);
            hold(app.UIAxes3,'off')

            hold(app.UIAxes,'on')
            area(app.UIAxes,app.vector_tiempo,app.ones_area,'LineWidth',2,'FaceAlpha',0.5)
            plot(app.UIAxes,app.vector_tiempo,app.pupil_positions_data.diameter(app.pupil_positions_index),'-b','LineWidth',1)
            app.h3 = xline(app.UIAxes,app.vector_tiempo(1),'-r','LineWidth',1);
            hold(app.UIAxes,'off')

            title(app.UIAxes3,"EEG ("+app.canales(app.canal_index)+")")
            app.h1 = xline(app.UIAxes2,app.vector_tiempo(1),'-blue','LineWidth',1);
            
            %%%%
            %%%%
            app.CerrarButton.Enable = true;
            app.PlayPausaButton.Text = "Play";
            app.playing = 0;
            app.PlayPausaButton.Enable = true;
            app.SiguienteButton.Enable = true;
            app.CanalDropDown.Enable = true;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: PlayPausaButton
        function PlayPausaButtonPushed(app, event)
            if app.playing == 0
                app.PlayPausaButton.Text = 'Pausa';
                app.playing = 1;
                app.CerrarButton.Enable = false;             
            else
                app.playing = 0;
                app.PlayPausaButton.Text = 'Play';
                app.CerrarButton.Enable = true;
             end    
            ndata = app.indices(end,2)-app.indices(1,1)+1;
            while app.playing == 1
                if app.valor <= ndata
                    %%
                    if app.valor < app.abs_ind(2)
                        app.AnteriorButton.Enable = false;
                    end
                    if app.valor >= app.abs_ind(end)
                        app.SiguienteButton.Enable = false;
                    end
                    %%
                    %% slider
                    app.TiempoSlider.Value = app.valor;
                    app.tiempoeditfieldEditField.Value = int2str(app.valor);
                    %% XLims
                    app.UIAxes2.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
                    app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
                    app.UIAxes.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
                    %% lines 
                    app.h1.Value = app.vector_tiempo(app.valor);
                    app.h2.Value = app.vector_tiempo(app.valor);
                    app.h3.Value = app.vector_tiempo(app.valor);
                    %% video
                    %frames_eye0 = read(app.eye0_data,app.eye0_index(app.valor));
                    app.Image3.ImageSource= flipud(read(app.eye0_data,app.eye0_index(app.valor)));
                    %otros 
                    app.Image.ImageSource = fullfile(app.BMPfiles_L,'heatmaps',char(app.heatname(app.valor)));
                    app.bpmEditField.Value = app.bpm(app.frec_card_index(app.valor),1);
                    %%% duracion - accion
                    app.ParteEditField.Value = app.dur_num(app.valor,1);
                    app.DuracinmsEditField.Value = app.dur_num(app.valor,2);
                    app.AccinEditField.Value = char(app.accion(app.valor));
                    drawnow limitrate
                    app.valor = app.valor + app.dpc_element;
                    
                else
                    app.valor = ndata;
                    app.SiguienteButton.Enable = false;
                    break
                end
            end
            % enables/disables
            app.CerrarButton.Enable = true;
            app.playing = 0;
           
            app.PlayPausaButton.Text = "Play";
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            app.valor = round(app.TiempoSlider.Value);
            app.tiempoeditfieldEditField.Value = int2str(app.valor);
             %% slider
            app.TiempoSlider.Value = app.valor;
            app.tiempoeditfieldEditField.Value = int2str(app.valor);
            %% XLims
            app.UIAxes2.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            %% lines 
            app.h1.Value = app.vector_tiempo(app.valor);
            app.h2.Value = app.vector_tiempo(app.valor);
            app.h3.Value = app.vector_tiempo(app.valor);
            %% video
            %frames_eye0 = read(app.eye0_data,app.eye0_index(app.valor));
            app.Image3.ImageSource = flipud(read(app.eye0_data,app.eye0_index(app.valor)));
            %otros 
            app.Image.ImageSource=fullfile(app.BMPfiles_L,'heatmaps',char(app.heatname(app.valor)));
            app.bpmEditField.Value = app.bpm(app.frec_card_index(app.valor),1);
            %%% duracion - accion
            app.ParteEditField.Value = app.dur_num(app.valor,1);
            app.DuracinmsEditField.Value = app.dur_num(app.valor,2);
            app.AccinEditField.Value = char(app.accion(app.valor));
        end

        % Value changed function: CanalDropDown
        function CanalDropDownValueChanged(app, event)
            app.canal_index = app.CanalDropDown.ItemsData(app.CanalDropDown.Value);
            cla(app.UIAxes3, 'reset'); 
            app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes3.YLim = [min(min(app.EEG_data(:,2:end)))/5,max(max(app.EEG_data(:,2:end)))/5];
            
            app.UIAxes3.XGrid = 'on';
            app.UIAxes3.YGrid = 'on';
            xlabel(app.UIAxes3, 'Tiempo (s)')
            ylabel(app.UIAxes3, 'Potencial uV')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.FontSize = 10;
             app.UIAxes3.Position = [224 173 409 185];
            
            hold(app.UIAxes3,'on')
            app.eeg_chanel = app.EEG_grafica(app.indices(1,1):app.indices(end,2),app.canal_index+1);
            area(app.UIAxes3,app.vector_tiempo,app.ones_area*1000,'LineWidth',2,'FaceAlpha',0.5)
            area(app.UIAxes3,app.vector_tiempo,-app.ones_area*1000,'LineWidth',2,'FaceAlpha',0.5)
            plot(app.UIAxes3,app.vector_tiempo,app.eeg_chanel,'-b','LineWidth',1)
            app.h2=xline(app.UIAxes3,app.vector_tiempo(app.valor),'-r','LineWidth',1);
            hold(app.UIAxes3,'off')
            title(app.UIAxes3,"EEG ("+app.canales(app.canal_index)+")")
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: DatosCicloDropDown
        function DatosCicloDropDownValueChanged(app, event)
            app.dpc_element = str2double(app.DatosCicloDropDown.Value(2:end));
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            sig_cond = app.abs_ind > app.valor;
            sig_ind2 = app.abs_ind(sig_cond);
            app.valor = sig_ind2(1);
            if app.valor >= app.abs_ind(end)
                app.SiguienteButton.Enable = false;
            end
            if app.valor >= app.abs_ind(2)
                app.AnteriorButton.Enable = true;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             %% slider
            app.TiempoSlider.Value = app.valor;
            app.tiempoeditfieldEditField.Value = int2str(app.valor);
            %% XLims
            app.UIAxes2.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes.XLim  = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            %% lines 
            app.h1.Value = app.vector_tiempo(app.valor);
            app.h2.Value = app.vector_tiempo(app.valor);
            app.h3.Value = app.vector_tiempo(app.valor);
            %% video
            %frames_eye0 = read(app.eye0_data,app.eye0_index(app.valor));
            app.Image3.ImageSource = flipud(read(app.eye0_data,app.eye0_index(app.valor)));
            %otros 
            app.Image.ImageSource = fullfile(app.BMPfiles_L,'heatmaps',char(app.heatname(app.valor)));
            app.bpmEditField.Value = app.bpm(app.frec_card_index(app.valor),1);
            %%% duracion - accion
            app.ParteEditField.Value = app.dur_num(app.valor,1);
            app.DuracinmsEditField.Value = app.dur_num(app.valor,2);
            app.AccinEditField.Value = char(app.accion(app.valor));
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            ant_cond = app.abs_ind < app.valor;
            ant_ind2 = app.abs_ind(ant_cond);
            app.valor = ant_ind2(end);
            if app.valor < app.abs_ind(2)
                app.AnteriorButton.Enable = false;
            end
            if app.valor < app.abs_ind(end)
                app.SiguienteButton.Enable = true;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% slider
            app.TiempoSlider.Value = app.valor;
            app.tiempoeditfieldEditField.Value = int2str(app.valor);
            %% XLims
            app.UIAxes2.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes3.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            app.UIAxes.XLim = [app.vector_tiempo(app.valor)-2,app.vector_tiempo(app.valor)+2];
            %% lines 
            app.h1.Value = app.vector_tiempo(app.valor);
            app.h2.Value = app.vector_tiempo(app.valor);
            app.h3.Value = app.vector_tiempo(app.valor);
            %% video
            %frames_eye0 = read(app.eye0_data,app.eye0_index(app.valor));
            app.Image3.ImageSource = flipud(read(app.eye0_data,app.eye0_index(app.valor)));
            %otros 
            app.Image.ImageSource=fullfile(app.BMPfiles_L,'heatmaps',char(app.heatname(app.valor)));
            app.bpmEditField.Value = app.bpm(app.frec_card_index(app.valor),1);
            %%% duracion - accion
            app.ParteEditField.Value = app.dur_num(app.valor,1);
            app.DuracinmsEditField.Value = app.dur_num(app.valor,2);
            app.AccinEditField.Value = char(app.accion(app.valor));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diámetro de la pupila / Parpadeo')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Diámetro (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ECG')
            xlabel(app.UIAxes2, 'Tiempo (s)')
            ylabel(app.UIAxes2, 'Potencial (uV)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'EEG (canal)')
            xlabel(app.UIAxes3, 'Tiempo (s)')
            ylabel(app.UIAxes3, 'Potencial (uV)')
            zlabel(app.UIAxes3, 'Z')
            app.directoriodataEditField = uieditfield(app.UIFigure, 'text');
            app.directoriobmppngEditField = uieditfield(app.UIFigure, 'text');
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.SelecBMPButton = uibutton(app.UIFigure, 'push');
            app.SelecBMPButton.ButtonPushedFcn = createCallbackFcn(app, @SelecBMPButtonPushed, true);
            app.SelecBMPButton.Text = 'Selec. BMP';
            app.AbrircarpetaButton = uibutton(app.UIFigure, 'push');
            app.AbrircarpetaButton.ButtonPushedFcn = createCallbackFcn(app, @AbrircarpetaButtonPushed, true);
            app.AbrircarpetaButton.Text = 'Abrir carpeta';
            app.exportListBox = uilistbox(app.UIFigure);
            app.exportListBox.Items = {'000', '001', '002', '004'};
            app.exportListBox.ValueChangedFcn = createCallbackFcn(app, @exportListBoxValueChanged, true);
            app.exportListBox.Value = '000';
            app.outputsysTextArea = uitextarea(app.UIFigure);
            app.outputsysTextArea.Value = {'system output'};
            app.Image = uiimage(app.UIFigure);
            app.CargardatosButton = uibutton(app.UIFigure, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.AnteriorButton = uibutton(app.UIFigure, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.SiguienteButton = uibutton(app.UIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.CargarHeatmapspreviosCheckBox = uicheckbox(app.UIFigure);
            app.CargarHeatmapspreviosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CargarHeatmapspreviosCheckBoxValueChanged, true);
            app.CargarHeatmapspreviosCheckBox.Text = 'Cargar Heatmaps previos';
            app.DatosCicloDropDownLabel = uilabel(app.UIFigure);
            app.DatosCicloDropDownLabel.Text = 'Datos/Ciclo';
            app.DatosCicloDropDown = uidropdown(app.UIFigure);
            app.DatosCicloDropDown.Items = {'1/10/20/50'};
            app.DatosCicloDropDown.ValueChangedFcn = createCallbackFcn(app, @DatosCicloDropDownValueChanged, true);
            app.DatosCicloDropDown.Value = '1/10/20/50';
            app.Image2 = uiimage(app.UIFigure);
            app.bpmEditFieldLabel = uilabel(app.UIFigure);
            app.bpmEditFieldLabel.Text = 'bpm';
            app.bpmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakDistanceEditFieldLabel.Text = 'MinPeakDistance';
            app.MinPeakDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistanceEditFieldValueChanged, true);
            app.MinPeakHeightuVEditFieldLabel = uilabel(app.UIFigure);
            app.MinPeakHeightuVEditFieldLabel.Text = 'MinPeakHeight uV';
            app.MinPeakHeightuVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPeakHeightuVEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakHeightuVEditFieldValueChanged, true);
            app.TiempoSliderLabel = uilabel(app.UIFigure);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.UIFigure);
            app.TiempoSlider.MajorTicks = [];
            app.TiempoSlider.MajorTickLabels = {''};
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.TiempoSlider.MinorTicks = [];
            app.PlayPausaButton = uibutton(app.UIFigure, 'push');
            app.PlayPausaButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPausaButtonPushed, true);
            app.PlayPausaButton.Text = 'Play/Pausa';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.CanalDropDownLabel = uilabel(app.UIFigure);
            app.CanalDropDownLabel.Text = 'Canal';
            app.CanalDropDown = uidropdown(app.UIFigure);
            app.CanalDropDown.Items = {'Cz', 'Option 2', 'Option 3', 'Option 4'};
            app.CanalDropDown.ValueChangedFcn = createCallbackFcn(app, @CanalDropDownValueChanged, true);
            app.CanalDropDown.Value = 'Cz';
            app.ParteEditFieldLabel = uilabel(app.UIFigure);
            app.ParteEditFieldLabel.Text = 'Parte';
            app.ParteEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuracinmsEditFieldLabel = uilabel(app.UIFigure);
            app.DuracinmsEditFieldLabel.Text = 'Duración (ms)';
            app.DuracinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccinEditFieldLabel = uilabel(app.UIFigure);
            app.AccinEditFieldLabel.Text = 'Acción';
            app.AccinEditField = uieditfield(app.UIFigure, 'text');
            app.AccinEditField.Value = 'Bala/Nada/Timeout';
            app.TiempodeRespuestaLabel = uilabel(app.UIFigure);
            app.TiempodeRespuestaLabel.Text = '[Tiempo de Respuesta]';
            app.tiempoeditfieldEditField = uieditfield(app.UIFigure, 'text');
            app.tiempoeditfieldEditField.Value = '0';
            app.Image3 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultados_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end